classdef DrawFigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DrawFigureUIFigure          matlab.ui.Figure
        RightPanel                  matlab.ui.container.Panel
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        LoadParametersButton        matlab.ui.control.Button
        SaveParametersButton        matlab.ui.control.Button
        LimitedDataEditField        matlab.ui.control.NumericEditField
        LimitedDataSwitch           matlab.ui.control.Switch
        LegendLabelsEditField       matlab.ui.control.EditField
        ErrorSwitch                 matlab.ui.control.Switch
        LegendSwitch                matlab.ui.control.Switch
        SiginificanceSwitch         matlab.ui.control.Switch
        Tree                        matlab.ui.container.CheckBoxTree
        FormatNode                  matlab.ui.container.TreeNode
        tifNode                     matlab.ui.container.TreeNode
        epfNode                     matlab.ui.container.TreeNode
        pdfNode                     matlab.ui.container.TreeNode
        DrawButton                  matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        SaveSwitch                  matlab.ui.control.Switch
        LeftPanel                   matlab.ui.container.Panel
        TitleTextArea               matlab.ui.control.TextArea
        TitleTextAreaLabel          matlab.ui.control.Label
        OutlierRemoveDropDown       matlab.ui.control.DropDown
        OutlierRemoveDropDownLabel  matlab.ui.control.Label
        FontSizeEditField           matlab.ui.control.EditField
        FontSizeEditFieldLabel      matlab.ui.control.Label
        FontNameEditField           matlab.ui.control.EditField
        FontNameEditFieldLabel      matlab.ui.control.Label
        XTickLableTextArea          matlab.ui.control.TextArea
        XTickLableTextAreaLabel     matlab.ui.control.Label
        XLabelTextArea              matlab.ui.control.TextArea
        xLabelTextAreaLabel         matlab.ui.control.Label
        FigureTypeDropDown          matlab.ui.control.DropDown
        FigureTypeDropDownLabel     matlab.ui.control.Label
        SettingsLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        Dataset
    end
    
    properties (Access = private)
        FuncDir = addpath('./Functions');  % Dummy variable to add functions folder to search path 
        ExampleDir = addpath('./Examples');  % Dummy variable to add functions folder to search path 
    end

    methods (Access = private)

        function results = SaveParameters(app)

        end
        
        %Create figures after draw button pushed
        function  CreateFigures(app)                 
                LineChart2(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonCallbackFun(app, event)
            [app.Dataset, path] = uigetfile('*.mat', 'Select an .mat data', '.\Examples\Dataset.mat');
            if isequal(app.Dataset,0)
                disp('User selected Cancel')
            else
                disp(['Data loaded ', fullfile(path, file)])
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonCallbackFun(app, event)

            app.CreateFigures();
        end

        % Callback function: not associated with a component
        function SettingsPushToolCallbackFun(app, event)
            app.LeftPanel.Visible = "on";
            app.RightPanel.Visible = "on";
        end

        % Callback function: not associated with a component
        function FiguresPushToolCallbackFun(app, event)
            app.LeftPanel.Visible = "off";
            app.RightPanel.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DrawFigureUIFigure = uifigure('Visible', 'off');
            app.DrawFigureUIFigure.Name = 'DrawFigure';
            app.DrawFigureUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings.jpg');
            app.LeftPanel = uipanel(app.DrawFigureUIFigure);
            app.SettingsLabel = uilabel(app.LeftPanel);
            app.SettingsLabel.Text = 'Settings';
            app.FigureTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FigureTypeDropDownLabel.Text = 'Figure Type';
            app.FigureTypeDropDown = uidropdown(app.LeftPanel);
            app.FigureTypeDropDown.Items = {'Bar', 'Line', 'Violin', 'Box'};
            app.FigureTypeDropDown.Value = 'Bar';
            app.xLabelTextAreaLabel = uilabel(app.LeftPanel);
            app.xLabelTextAreaLabel.Text = 'XLabel';
            app.XLabelTextArea = uitextarea(app.LeftPanel);
            app.XLabelTextArea.Value = {'DIV'};
            app.XTickLableTextAreaLabel = uilabel(app.LeftPanel);
            app.XTickLableTextAreaLabel.Text = 'XTickLable';
            app.XTickLableTextArea = uitextarea(app.LeftPanel);
            app.XTickLableTextArea.Value = {'{3,4,5,6,7,9,11}'};
            app.FontNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FontNameEditFieldLabel.Text = 'FontName';
            app.FontNameEditField = uieditfield(app.LeftPanel, 'text');
            app.FontNameEditField.Value = 'Arial';
            app.FontSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.FontSizeEditField = uieditfield(app.LeftPanel, 'text');
            app.FontSizeEditField.Value = '10';
            app.OutlierRemoveDropDownLabel = uilabel(app.LeftPanel);
            app.OutlierRemoveDropDownLabel.Text = 'OutlierRemove';
            app.OutlierRemoveDropDown = uidropdown(app.LeftPanel);
            app.OutlierRemoveDropDown.Items = {'none', 'quartiles', 'median'};
            app.OutlierRemoveDropDown.Value = 'none';
            app.TitleTextAreaLabel = uilabel(app.LeftPanel);
            app.TitleTextAreaLabel.Text = 'Title';
            app.TitleTextArea = uitextarea(app.LeftPanel);
            app.TitleTextArea.Placeholder = 'No. of 1st dendrite';
            app.RightPanel = uipanel(app.DrawFigureUIFigure);
            app.SaveSwitch = uiswitch(app.RightPanel, 'slider');
            app.SaveSwitch.Items = {'AutoSave', ''};
            app.SaveSwitch.Value = 'AutoSave';
            app.LoadDataButton = uibutton(app.RightPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonCallbackFun, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DrawButton = uibutton(app.RightPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonCallbackFun, true);
            app.DrawButton.Text = 'Draw';
            app.Tree = uitree(app.RightPanel, 'checkbox');
            app.FormatNode = uitreenode(app.Tree);
            app.FormatNode.Text = 'Format';
            app.tifNode = uitreenode(app.FormatNode);
            app.tifNode.Text = 'tif';
            app.epfNode = uitreenode(app.FormatNode);
            app.epfNode.Text = 'epf';
            app.pdfNode = uitreenode(app.FormatNode);
            app.pdfNode.Text = 'pdf';
            app.SiginificanceSwitch = uiswitch(app.RightPanel, 'slider');
            app.SiginificanceSwitch.Items = {'Significance', ''};
            app.SiginificanceSwitch.Value = 'Significance';
            app.LegendSwitch = uiswitch(app.RightPanel, 'slider');
            app.LegendSwitch.Items = {'Legend', ''};
            app.LegendSwitch.Value = 'Legend';
            app.ErrorSwitch = uiswitch(app.RightPanel, 'slider');
            app.ErrorSwitch.Items = {'Error', ''};
            app.ErrorSwitch.Value = 'Error';
            app.LegendLabelsEditField = uieditfield(app.RightPanel, 'text');
            app.LimitedDataSwitch = uiswitch(app.RightPanel, 'slider');
            app.LimitedDataSwitch.Items = {'LimitedData', ''};
            app.LimitedDataSwitch.Value = 'LimitedData';
            app.LimitedDataEditField = uieditfield(app.RightPanel, 'numeric');
            app.LimitedDataEditField.Value = 1;
            app.SaveParametersButton = uibutton(app.RightPanel, 'push');
            app.SaveParametersButton.Text = 'Save Parameters';
            app.LoadParametersButton = uibutton(app.RightPanel, 'push');
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ResolutionEditFieldLabel = uilabel(app.RightPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResolutionEditField.Value = 1200;
            % Show the figure after all components are created
            app.DrawFigureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrawFigure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DrawFigureUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DrawFigureUIFigure)
        end
    end
end